classdef userInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Toolbar                        matlab.ui.container.Toolbar
        loadSourceDataShortcut         matlab.ui.container.toolbar.PushTool
        refreshShortcut                matlab.ui.container.toolbar.PushTool
        GridLayout                     matlab.ui.container.GridLayout
        TabGroup2                      matlab.ui.container.TabGroup
        NastaveniamodeluTab            matlab.ui.container.Tab
        GridLayout5                    matlab.ui.container.GridLayout
        MapaDropDown                   matlab.ui.control.DropDown
        MapaDropDownLabel              matlab.ui.control.Label
        PrahSpinner                    matlab.ui.control.Spinner
        PrahSlider                     matlab.ui.control.Slider
        PrahLabel                      matlab.ui.control.Label
        CullingSpinner                 matlab.ui.control.Spinner
        CullingSpinnerLabel            matlab.ui.control.Label
        Tab                            matlab.ui.container.Tab
        GridLayout7                    matlab.ui.container.GridLayout
        PrahOverlaySlider              matlab.ui.control.Slider
        PrahOverlayLabel               matlab.ui.control.Label
        PrahOverlaySpinner             matlab.ui.control.Spinner
        MapaOverlayDropDown            matlab.ui.control.DropDown
        FarebnosmodeluDropDown_2Label  matlab.ui.control.Label
        CullingOverlaySpinner          matlab.ui.control.Spinner
        CullingSpinner_2Label          matlab.ui.control.Label
        OvldanieoknaTab                matlab.ui.container.Tab
        GridLayout6                    matlab.ui.container.GridLayout
        GridLayout2                    matlab.ui.container.GridLayout
        StopkaButton                   matlab.ui.control.Button
        Stav                           matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        NataniesnmokTab                matlab.ui.container.Tab
        GridLayout3                    matlab.ui.container.GridLayout
        InterpolaciaDropDown           matlab.ui.control.DropDown
        InterpolciaDropDownLabel       matlab.ui.control.Label
        GridLayout9_2                  matlab.ui.container.GridLayout
        Label_2                        matlab.ui.control.Label
        Image_2                        matlab.ui.control.Image
        GridLayout9                    matlab.ui.container.GridLayout
        Label                          matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        UloimodelButton                matlab.ui.control.Button
        MenosboruEditField             matlab.ui.control.EditField
        MenosboruEditFieldLabel        matlab.ui.control.Label
        VytvoritModelButton            matlab.ui.control.Button
        GridLayout4                    matlab.ui.container.GridLayout
        StopSpinner                    matlab.ui.control.Spinner
        akoncovousnmkouSpinnerLabel    matlab.ui.control.Label
        StartSpinner                   matlab.ui.control.Spinner
        spoiatonousnmkouSpinnerLabel   matlab.ui.control.Label
        snmkuLabel                     matlab.ui.control.Label
        StepSpinner                    matlab.ui.control.Spinner
        pouikadSpinnerLabel            matlab.ui.control.Label
        DostupneSadyListBox            matlab.ui.control.ListBox
        DostupnsadysnmokListBoxLabel   matlab.ui.control.Label
        ZobrazenieTab                  matlab.ui.container.Tab
        GridLayout8                    matlab.ui.container.GridLayout
        ZobrazitVybraneButton          matlab.ui.control.StateButton
        ModelOverlayDropDown           matlab.ui.control.DropDown
        ModelnapreloenieDropDownLabel  matlab.ui.control.Label
        ModelDropDown                  matlab.ui.control.DropDown
        ModelDropDownLabel             matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        stopkaStatus % Description
        sourceData % Description
        volumeData % Description
    end
    
    properties (Access = public)
        zobrazenieVlastnosti % Description
        modelView % Description
    end
    
    methods (Access = private)
        
        function uhOh(app,hlaska) %tato funkcia sa vola vtedy, ked aplikacia narazi napriklad na nejaky chybny vstup. pauzne vykonavanie kym pouzivatel neodsuhlasi pokracovanie
            app.StopkaButton.Icon = 'icon-mat-thumb-up.png';
            app.stopkaStatus=true;
            app.Stav.Text=string(hlaska);
            app.StopkaButton.Enable=true;
            uiwait(app.UIFigure);
        end
        
        
        function redraw(app)
            app.Stav.Text="Prekresľujem zobrazenie...";

            if app.volumeData.HlavnyZobrazeny
                Hlavny = modelVisualizer(app.modelView.Volume, app.modelView.X_grid, app.modelView.Y_grid, app.modelView.Z_grid, app.UIAxes, app.zobrazenieVlastnosti.Hlavny, true);
                
                set(Hlavny, 'EdgeColor', 'none');

                app.CullingSpinner.Enable = "on"; app.CullingSpinnerLabel.Enable = "on";
                app.PrahSpinner.Enable = "on"; app.PrahSlider.Enable = "on"; app.PrahLabel.Enable = "on";
                app.MapaDropDown.Enable = "on"; app.MapaDropDownLabel.Enable = "on";

                app.Stav.Text = "Zobrazenie úspešne prekreslené!";
            elseif app.volumeData.OverlayZobrazeny
                % TBA
            elseif app.volumeData.HlavnyZobrazeny && app.volumeData.OverlayZobrazeny
                % TBA
            else
                app.UIAxes.Visible = "off";

                app.CullingSpinner.Enable = "off"; app.CullingSpinnerLabel.Enable = "off";
                app.PrahSpinner.Enable = "off"; app.PrahSlider.Enable = "off"; app.PrahLabel.Enable = "off";
                app.MapaDropDown.Enable = "off"; app.MapaDropDownLabel.Enable = "off";

                app.Stav.Text = "Nie je čo zobraziť!";
            end

            daspect([1 1 1]); view(3); grid on;
        end
    end
    
    methods (Access = public)


        function fileList = makeModelFileList(app,useSkips)
            fileList = {};
            for i = 1:length(app.DostupneSadyListBox.Value)
                workingFilesDir = dir(app.sourceData.Path+'\'+string(app.DostupneSadyListBox.Value(i)));
                workingFilesDir(1:2) = [];

                if useSkips.tf == true
                    if isempty(useSkips.stop) == true
                        index = useSkips.start:useSkips.step:length(workingFilesDir);
                    else
                        index = useSkips.start:useSkips.step:useSkips.stop;
                    end

                    workingFileList = strings(1,length(index));

                    for j = 1:length(index)
                        workingFileList(j) = app.sourceData.Path+'\'+app.sourceData.Content(i).name+'\'+workingFilesDir(index(j)).name;
                    end
                else
                    workingFileList = strings(1,length(workingFilesDir));

                    for j = 1:length(workingFilesDir)
                        workingFileList(j) = app.sourceData.Path+'\'+app.sourceData.Content(i).name+'\'+workingFilesDir(j).name;
                    end
                end

                fileList = [fileList,workingFileList];
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath(pwd));

            [app.sourceData.Content,app.sourceData.Path,inputName,app.volumeData.Content,app.volumeData.Path,volumeName]=loadSourceData(app.Stav);
            
            if ~isempty(inputName)
                app.DostupneSadyListBox.Items = inputName(:);
            else
                app.DostupneSadyListBox.Items = [];

                if isempty(volumeName)
                    uhOh(app,"Vstupné dáta neboli úspešne načítané. Naplňte vstupný priečinok sourceData DICOM snímkami rozdelenými do podpriečinkov a znovu spustite ich načítavanie.");
                else
                    app.Stav.Text = "Nemáte žiadne vstupné dáta, ale máte uložené modely. Toto je chyba len v prípade, že vstupné dáta máte a skript ich nevie nájsť.";
                end
            end

            if ~isempty(volumeName)
                app.ModelDropDown.Items = ["BEZ MODELU";volumeName(:)];
                app.ModelOverlayDropDown.Items = ["BEZ MODELU";volumeName(:)];
            else
                app.ModelDropDown.Items = "BEZ MODELU";
                app.ModelOverlayDropDown.Items = "BEZ MODELU";
                app.Stav.Text = "Nemáte žiadne uložené modely. Toto je chyba len v prípade, že uložené modely máte a skript ich nevie nájsť.";
            end

            app.zobrazenieVlastnosti.Hlavny.cull = app.CullingSpinner.Value;
            app.zobrazenieVlastnosti.Overlay.cull = app.CullingOverlaySpinner.Value;

            app.zobrazenieVlastnosti.Hlavny.maskThreshold = app.PrahSlider.Value;
            app.zobrazenieVlastnosti.Overlay.maskThreshold = app.PrahOverlaySlider.Value;

            app.zobrazenieVlastnosti.Interpolacia = 'vypnutá';
            % app.zobrazenieVlastnosti.PrahOverlay = app.MaskThresholdMapySlider.Value;
            % app.zobrazenieVlastnosti.multiplier = app.MaskMultiplier.Value;
            % app.zobrazenieVlastnosti.View = 'orthographic';

            app.zobrazenieVlastnosti.ZobrazitUlozene = false;
            app.volumeData.HlavnyZobrazeny = false;
            app.volumeData.OverlayZobrazeny = false;

            app.zobrazenieVlastnosti.Hlavny.cmapName = "gray";
            app.zobrazenieVlastnosti.Overlay.cmapName = "parula";

            app.sourceData.StepZmeneny = false;
            app.ZobrazitVybraneButton.Value = app.zobrazenieVlastnosti.ZobrazitUlozene;

            app.Stav.Text="Čakám na vstup...";
        end

        % Button pushed function: StopkaButton
        function StopkaButtonPushed(app, event)
            app.stopkaStatus=false;
            uiresume(app.UIFigure);
            app.StopkaButton.Enable=false;
            app.StopkaButton.Icon = 'icon-mat-help-outline.png';
            app.Stav.Text="Čakám na vstup...";
        end

        % Value changed function: DostupneSadyListBox
        function DostupneSadyListBoxValueChanged(app, event)
            value = app.DostupneSadyListBox.Value;

            if isempty(value)
                app.VytvoritModelButton.Enable = "off";
            else
                app.VytvoritModelButton.Enable = "on";
            end
        end

        % Button pushed function: VytvoritModelButton
        function VytvoritModelButtonPushed(app, event)
            app.Stav.Text = "Vytváram model...";

            if app.sourceData.StepZmeneny == true
                useSkips.tf = true;
                useSkips.start = app.StartSpinner.Value;
                useSkips.stop = app.StopSpinner.Value;
                useSkips.step = app.StepSpinner.Value;
            else
                useSkips.tf=false;
            end

            fileList = makeModelFileList(app,useSkips);

            if isempty(fileList)
                uhOh(app,"Nebolo možné vytvoriť model. Gratulujem, táto chybová hláška je veľmi vzácna.");
            else
                if strcmp(app.zobrazenieVlastnosti.Interpolacia,'vypnutá')
                    interpolacia = false;
                elseif strcmp(app.zobrazenieVlastnosti.Interpolacia,'scatteredInterpolant')
                    interpolacia = true;
                end

                [app.modelView.Volume, app.modelView.X_grid, app.modelView.Y_grid, app.modelView.Z_grid] = PlanesToVolume(fileList, interpolacia, app.Stav);

                app.Stav.Text="Zobrazujem model...";
                app.volumeData.HlavnyZobrazeny = true;
                
                success = true;
                try
                    redraw(app);
                catch
                    success = false;
                    uhOh(app,"Niečo sa katastroficky pokazilo pri vykresľovaní modelu. Gratulujem!")
                end
                
                if ~success
                    app.Stav.Text="Model zobrazeny!";
                end
            end
        end

        % Callback function: refreshShortcut
        function refreshShortcutClicked(app, event)
            redraw(app);
        end

        % Value changed function: InterpolaciaDropDown
        function InterpolaciaDropDownValueChanged(app, event)
            app.zobrazenieVlastnosti.Interpolacia = app.InterpolaciaDropDown.Value;
        end

        % Value changed function: CullingSpinner
        function CullingSpinnerValueChanged(app, event)
            app.zobrazenieVlastnosti.Hlavny.cull = app.CullingSpinner.Value;
        end

        % Value changed function: PrahSpinner
        function PrahSpinnerValueChanged(app, event)
            app.zobrazenieVlastnosti.Hlavny.maskThreshold = app.PrahSpinner.Value;
            app.PrahSlider.Value = app.zobrazenieVlastnosti.Hlavny.maskThreshold;
        end

        % Value changed function: PrahSlider
        function PrahSliderValueChanged(app, event)
            app.zobrazenieVlastnosti.Hlavny.maskThreshold = app.PrahSlider.Value;
            app.PrahSpinner.Value = app.zobrazenieVlastnosti.Hlavny.maskThreshold;
        end

        % Value changed function: MapaDropDown
        function MapaDropDownValueChanged(app, event)
            app.zobrazenieVlastnosti.Hlavny.cmapName = app.MapaDropDown.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.loadSourceDataShortcut = uipushtool(app.Toolbar);
            app.loadSourceDataShortcut.Tooltip = {'Znovu načíta zdroje z priečinkov sourceData a niiData.'};
            app.loadSourceDataShortcut.Icon = fullfile(pathToMLAPP, 'guiResources', 'icon-mat-refresh.png');
            app.refreshShortcut = uipushtool(app.Toolbar);
            app.refreshShortcut.ClickedCallback = createCallbackFcn(app, @refreshShortcutClicked, true);
            app.refreshShortcut.Icon = fullfile(pathToMLAPP, 'guiResources', 'icon-mat-3d-rotation.png');
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.UIAxes.ZTick = [];
            app.TabGroup = uitabgroup(app.GridLayout);
            app.NataniesnmokTab = uitab(app.TabGroup);
            app.NataniesnmokTab.Title = 'Načítanie snímok';
            app.GridLayout3 = uigridlayout(app.NataniesnmokTab);
            app.DostupnsadysnmokListBoxLabel = uilabel(app.GridLayout3);
            app.DostupnsadysnmokListBoxLabel.Text = 'Dostupné sady snímok';
            app.DostupneSadyListBox = uilistbox(app.GridLayout3);
            app.DostupneSadyListBox.Multiselect = 'on';
            app.DostupneSadyListBox.ValueChangedFcn = createCallbackFcn(app, @DostupneSadyListBoxValueChanged, true);
            app.DostupneSadyListBox.Value = {'Item 1'};
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.pouikadSpinnerLabel = uilabel(app.GridLayout4);
            app.pouikadSpinnerLabel.Text = 'použiť každú';
            app.StepSpinner = uispinner(app.GridLayout4);
            app.StepSpinner.Limits = [1 Inf];
            app.StepSpinner.RoundFractionalValues = 'on';
            app.StepSpinner.Value = 1;
            app.snmkuLabel = uilabel(app.GridLayout4);
            app.snmkuLabel.Text = 'snímku';
            app.spoiatonousnmkouSpinnerLabel = uilabel(app.GridLayout4);
            app.spoiatonousnmkouSpinnerLabel.Text = 's počiatočnou snímkou';
            app.StartSpinner = uispinner(app.GridLayout4);
            app.StartSpinner.Limits = [1 Inf];
            app.StartSpinner.RoundFractionalValues = 'on';
            app.StartSpinner.Value = 1;
            app.akoncovousnmkouSpinnerLabel = uilabel(app.GridLayout4);
            app.akoncovousnmkouSpinnerLabel.Text = 'a koncovou snímkou';
            app.StopSpinner = uispinner(app.GridLayout4);
            app.StopSpinner.Limits = [1 Inf];
            app.StopSpinner.RoundFractionalValues = 'on';
            app.StopSpinner.Value = 1;
            app.VytvoritModelButton = uibutton(app.GridLayout3, 'push');
            app.VytvoritModelButton.ButtonPushedFcn = createCallbackFcn(app, @VytvoritModelButtonPushed, true);
            app.VytvoritModelButton.Text = 'Vytvoriť model';
            app.MenosboruEditFieldLabel = uilabel(app.GridLayout3);
            app.MenosboruEditFieldLabel.Text = 'Meno súboru';
            app.MenosboruEditField = uieditfield(app.GridLayout3, 'text');
            app.UloimodelButton = uibutton(app.GridLayout3, 'push');
            app.UloimodelButton.Text = 'Uložiť model';
            app.GridLayout9 = uigridlayout(app.GridLayout3);
            app.Image = uiimage(app.GridLayout9);
            app.Image.Enable = 'off';
            app.Image.ImageSource = fullfile(pathToMLAPP, 'guiResources', 'icon-mat-help-outline.png');
            app.Label = uilabel(app.GridLayout9);
            app.Label.Enable = 'off';
            app.Label.Text = 'Aby ste model vedeli preložiť iným modelom (pomocou záložky Zobrazenie), musíte ho najskôr uložiť. Pôvodné obrazové dáta ostávajú nezmenené.';
            app.GridLayout9_2 = uigridlayout(app.GridLayout3);
            app.Image_2 = uiimage(app.GridLayout9_2);
            app.Image_2.Enable = 'off';
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'guiResources', 'icon-mat-help-outline.png');
            app.Label_2 = uilabel(app.GridLayout9_2);
            app.Label_2.Enable = 'off';
            app.Label_2.Text = 'Pole umožňuje výber viacerých sád dát (optimálne troch). Pre ich výber podržte pri kliknutí klávesu Shift alebo Ctrl).';
            app.InterpolciaDropDownLabel = uilabel(app.GridLayout3);
            app.InterpolciaDropDownLabel.Tooltip = {'Pri mapách a snímkach s nízkym optickým kontrastom je vhodné ponechať interpoláciu vypnutú.'};
            app.InterpolciaDropDownLabel.Text = 'Interpolácia';
            app.InterpolaciaDropDown = uidropdown(app.GridLayout3);
            app.InterpolaciaDropDown.Items = {'vypnutá', 'scatteredInterpolant'};
            app.InterpolaciaDropDown.ValueChangedFcn = createCallbackFcn(app, @InterpolaciaDropDownValueChanged, true);
            app.InterpolaciaDropDown.Value = 'vypnutá';
            app.ZobrazenieTab = uitab(app.TabGroup);
            app.ZobrazenieTab.Title = 'Zobrazenie';
            app.GridLayout8 = uigridlayout(app.ZobrazenieTab);
            app.ModelDropDownLabel = uilabel(app.GridLayout8);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.GridLayout8);
            app.ModelDropDown.Items = {'BEZ MODELU'};
            app.ModelDropDown.Value = 'BEZ MODELU';
            app.ModelnapreloenieDropDownLabel = uilabel(app.GridLayout8);
            app.ModelnapreloenieDropDownLabel.Text = 'Model na preloženie';
            app.ModelOverlayDropDown = uidropdown(app.GridLayout8);
            app.ModelOverlayDropDown.Items = {'BEZ MODELU'};
            app.ModelOverlayDropDown.Value = 'BEZ MODELU';
            app.ZobrazitVybraneButton = uibutton(app.GridLayout8, 'state');
            app.ZobrazitVybraneButton.Text = 'Zobraziť vybrané modely';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.Stav = uilabel(app.GridLayout2);
            app.Stav.Text = 'Stavový riadok';
            app.StopkaButton = uibutton(app.GridLayout2, 'push');
            app.StopkaButton.ButtonPushedFcn = createCallbackFcn(app, @StopkaButtonPushed, true);
            app.StopkaButton.Icon = 'icon-mat-help-outline.png';
            app.StopkaButton.Enable = 'off';
            app.StopkaButton.Tooltip = {'Keď program narazí na chybu, zastaví sa. Prečítajte si chybovú hlášku a týmto tlačidlom ju odsúhlaste.'};
            app.StopkaButton.Text = '';
            app.TabGroup2 = uitabgroup(app.GridLayout);
            app.NastaveniamodeluTab = uitab(app.TabGroup2);
            app.NastaveniamodeluTab.Title = 'Nastavenia modelu';
            app.GridLayout5 = uigridlayout(app.NastaveniamodeluTab);
            app.CullingSpinnerLabel = uilabel(app.GridLayout5);
            app.CullingSpinnerLabel.Enable = 'off';
            app.CullingSpinnerLabel.Text = 'Culling';
            app.CullingSpinner = uispinner(app.GridLayout5);
            app.CullingSpinner.Limits = [1 Inf];
            app.CullingSpinner.RoundFractionalValues = 'on';
            app.CullingSpinner.ValueChangedFcn = createCallbackFcn(app, @CullingSpinnerValueChanged, true);
            app.CullingSpinner.Enable = 'off';
            app.CullingSpinner.Value = 4;
            app.PrahLabel = uilabel(app.GridLayout5);
            app.PrahLabel.Enable = 'off';
            app.PrahLabel.Text = 'Maskovací prah';
            app.PrahSlider = uislider(app.GridLayout5);
            app.PrahSlider.Limits = [0 1];
            app.PrahSlider.ValueChangedFcn = createCallbackFcn(app, @PrahSliderValueChanged, true);
            app.PrahSlider.Step = 0.05;
            app.PrahSlider.Enable = 'off';
            app.PrahSlider.Value = 0.2;
            app.PrahSpinner = uispinner(app.GridLayout5);
            app.PrahSpinner.Step = 0.05;
            app.PrahSpinner.Limits = [0 1];
            app.PrahSpinner.ValueChangedFcn = createCallbackFcn(app, @PrahSpinnerValueChanged, true);
            app.PrahSpinner.Enable = 'off';
            app.PrahSpinner.Value = 0.2;
            app.MapaDropDownLabel = uilabel(app.GridLayout5);
            app.MapaDropDownLabel.Enable = 'off';
            app.MapaDropDownLabel.Text = 'Farebnosť modelu';
            app.MapaDropDown = uidropdown(app.GridLayout5);
            app.MapaDropDown.Items = {'gray', 'bone', 'copper', 'pink', 'hot'};
            app.MapaDropDown.ValueChangedFcn = createCallbackFcn(app, @MapaDropDownValueChanged, true);
            app.MapaDropDown.Enable = 'off';
            app.MapaDropDown.Value = 'gray';
            app.Tab = uitab(app.TabGroup2);
            app.Tab.Title = 'Nastavenia zobrazenia preloženého modelu';
            app.GridLayout7 = uigridlayout(app.Tab);
            app.CullingSpinner_2Label = uilabel(app.GridLayout7);
            app.CullingSpinner_2Label.Enable = 'off';
            app.CullingSpinner_2Label.Text = 'Culling';
            app.CullingOverlaySpinner = uispinner(app.GridLayout7);
            app.CullingOverlaySpinner.Limits = [1 Inf];
            app.CullingOverlaySpinner.RoundFractionalValues = 'on';
            app.CullingOverlaySpinner.Enable = 'off';
            app.CullingOverlaySpinner.Value = 4;
            app.FarebnosmodeluDropDown_2Label = uilabel(app.GridLayout7);
            app.FarebnosmodeluDropDown_2Label.Enable = 'off';
            app.FarebnosmodeluDropDown_2Label.Text = 'Farebnosť modelu';
            app.MapaOverlayDropDown = uidropdown(app.GridLayout7);
            app.MapaOverlayDropDown.Items = {'parula', 'turbo', 'jet', 'lines', 'cool'};
            app.MapaOverlayDropDown.Enable = 'off';
            app.MapaOverlayDropDown.Value = 'parula';
            app.PrahOverlaySpinner = uispinner(app.GridLayout7);
            app.PrahOverlaySpinner.Enable = 'off';
            app.PrahOverlaySpinner.Value = 0.2;
            app.PrahOverlayLabel = uilabel(app.GridLayout7);
            app.PrahOverlayLabel.Enable = 'off';
            app.PrahOverlayLabel.Text = 'Maskovací prah';
            app.PrahOverlaySlider = uislider(app.GridLayout7);
            app.PrahOverlaySlider.Limits = [0 1];
            app.PrahOverlaySlider.Step = 0.05;
            app.PrahOverlaySlider.Enable = 'off';
            app.PrahOverlaySlider.Value = 0.2;
            app.OvldanieoknaTab = uitab(app.TabGroup2);
            app.OvldanieoknaTab.Title = 'Ovládanie okna';
            app.GridLayout6 = uigridlayout(app.OvldanieoknaTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = userInterface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end